classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RobertPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        Work                            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        STOPwork                        matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        ReadReportButton                matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
        ChooseSequenceListBoxLabel      matlab.ui.control.Label
        ChooseSequenceListBox           matlab.ui.control.ListBox
        Label_48                        matlab.ui.control.Label
        Label_49                        matlab.ui.control.Label
        Label_50                        matlab.ui.control.Label
        HelpButton_Main                 matlab.ui.control.Button
        ModuleManagementTab             matlab.ui.container.Tab
        AddNewModulePanel               matlab.ui.container.Panel
        TabGroup5                       matlab.ui.container.TabGroup
        CatalogTab                      matlab.ui.container.Tab
        AddToMyModulesFromCat           matlab.ui.control.Button
        BuyableModulesLabel             matlab.ui.control.Label
        CatalogueModulesListBox         matlab.ui.control.ListBox
        AddToMyModulesFromOwn           matlab.ui.control.Button
        RemoveSelectedModuleButton      matlab.ui.control.Button
        Label_15                        matlab.ui.control.Label
        Label_53                        matlab.ui.control.Label
        UserdefinedModulesListBoxLabel  matlab.ui.control.Label
        UserdefinedModulesListBox       matlab.ui.control.ListBox
        OwnModulesTab                   matlab.ui.container.Tab
        NameEditField_3Label            matlab.ui.control.Label
        NameOwnModule                   matlab.ui.control.EditField
        ExploreButton_3                 matlab.ui.control.Button
        STLEditFieldLabel               matlab.ui.control.Label
        STLTextArea                     matlab.ui.control.EditField
        TabGroup2                       matlab.ui.container.TabGroup
        LinkTab                         matlab.ui.container.Tab
        KinParLabel                     matlab.ui.control.Label
        DynParLabel                     matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        Label_90                        matlab.ui.control.Label
        mLabel_41                       matlab.ui.control.Label
        mLabel_42                       matlab.ui.control.Label
        mLabel_43                       matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        la                              matlab.ui.control.NumericEditField
        Label_91                        matlab.ui.control.Label
        lalpha                          matlab.ui.control.NumericEditField
        pLabel                          matlab.ui.control.Label
        lp                              matlab.ui.control.NumericEditField
        nLabel                          matlab.ui.control.Label
        ln                              matlab.ui.control.NumericEditField
        inLabel                         matlab.ui.control.Label
        ldeltain                        matlab.ui.control.NumericEditField
        outLabel                        matlab.ui.control.Label
        ldeltaout                       matlab.ui.control.NumericEditField
        InertialTensorILabel            matlab.ui.control.Label
        inkgm2Label                     matlab.ui.control.Label
        I11                             matlab.ui.control.NumericEditField
        I12                             matlab.ui.control.NumericEditField
        I13                             matlab.ui.control.NumericEditField
        I21                             matlab.ui.control.NumericEditField
        I22                             matlab.ui.control.NumericEditField
        I23                             matlab.ui.control.NumericEditField
        I31                             matlab.ui.control.NumericEditField
        I32                             matlab.ui.control.NumericEditField
        I33                             matlab.ui.control.NumericEditField
        rcLabel                         matlab.ui.control.Label
        lrcx                            matlab.ui.control.NumericEditField
        lrcy                            matlab.ui.control.NumericEditField
        lrcz                            matlab.ui.control.NumericEditField
        mLabel_44                       matlab.ui.control.Label
        mLabel_45                       matlab.ui.control.Label
        mLabel_46                       matlab.ui.control.Label
        radLabel_20                     matlab.ui.control.Label
        radLabel_21                     matlab.ui.control.Label
        radLabel_22                     matlab.ui.control.Label
        mLabel_47                       matlab.ui.control.Label
        lm                              matlab.ui.control.NumericEditField
        InputConnectorSizeEditFieldLabel  matlab.ui.control.Label
        linconnector                    matlab.ui.control.NumericEditField
        OutputConnectorSizeLabel        matlab.ui.control.Label
        loutconnector                   matlab.ui.control.NumericEditField
        AddLink                         matlab.ui.control.Button
        JointTab                        matlab.ui.container.Tab
        KinParLabel_2                   matlab.ui.control.Label
        DynParLabel_2                   matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        Label_92                        matlab.ui.control.Label
        mLabel_48                       matlab.ui.control.Label
        mLabel_49                       matlab.ui.control.Label
        mLabel_50                       matlab.ui.control.Label
        mLabel_51                       matlab.ui.control.Label
        mLabel_52                       matlab.ui.control.Label
        mLabel_53                       matlab.ui.control.Label
        jtLabel                         matlab.ui.control.Label
        jt                              matlab.ui.control.NumericEditField
        LjlLabel                        matlab.ui.control.Label
        ljl                             matlab.ui.control.NumericEditField
        UjlLabel                        matlab.ui.control.Label
        ujl                             matlab.ui.control.NumericEditField
        jLabel                          matlab.ui.control.Label
        jdeltaj                         matlab.ui.control.NumericEditField
        aplEditFieldLabel               matlab.ui.control.Label
        japl                            matlab.ui.control.NumericEditField
        plEditFieldLabel                matlab.ui.control.Label
        jalphapl                        matlab.ui.control.NumericEditField
        pplEditFieldLabel               matlab.ui.control.Label
        jppl                            matlab.ui.control.NumericEditField
        nplEditFieldLabel               matlab.ui.control.Label
        jnpl                            matlab.ui.control.NumericEditField
        plEditField_2Label              matlab.ui.control.Label
        jdeltapl                        matlab.ui.control.NumericEditField
        adlEditFieldLabel               matlab.ui.control.Label
        jadl                            matlab.ui.control.NumericEditField
        dlEditFieldLabel                matlab.ui.control.Label
        jalphadl                        matlab.ui.control.NumericEditField
        pdlLabel                        matlab.ui.control.Label
        jpdl                            matlab.ui.control.NumericEditField
        ndlEditFieldLabel               matlab.ui.control.Label
        jndl                            matlab.ui.control.NumericEditField
        dlEditField_2Label              matlab.ui.control.Label
        jdeltadl                        matlab.ui.control.NumericEditField
        kEditFieldLabel                 matlab.ui.control.Label
        ktau                            matlab.ui.control.NumericEditField
        krEditFieldLabel                matlab.ui.control.Label
        kr                              matlab.ui.control.NumericEditField
        IplEditFieldLabel               matlab.ui.control.Label
        jIpl11                          matlab.ui.control.NumericEditField
        jIpl12                          matlab.ui.control.NumericEditField
        jIpl13                          matlab.ui.control.NumericEditField
        jIpl22                          matlab.ui.control.NumericEditField
        jIpl23                          matlab.ui.control.NumericEditField
        jIpl33                          matlab.ui.control.NumericEditField
        rcplEditFieldLabel              matlab.ui.control.Label
        jrcplx                          matlab.ui.control.NumericEditField
        jrcply                          matlab.ui.control.NumericEditField
        jrcplz                          matlab.ui.control.NumericEditField
        limEditFieldLabel               matlab.ui.control.Label
        taulim                          matlab.ui.control.NumericEditField
        dqlimEditFieldLabel             matlab.ui.control.Label
        dqlim                           matlab.ui.control.NumericEditField
        ddqlimEditFieldLabel            matlab.ui.control.Label
        ddqlim                          matlab.ui.control.NumericEditField
        curlimEditFieldLabel            matlab.ui.control.Label
        curlim                          matlab.ui.control.NumericEditField
        IdlLabel                        matlab.ui.control.Label
        jIdl11                          matlab.ui.control.NumericEditField
        jIdl12                          matlab.ui.control.NumericEditField
        jIdl13                          matlab.ui.control.NumericEditField
        jIdl22                          matlab.ui.control.NumericEditField
        jIdl23                          matlab.ui.control.NumericEditField
        jIdl33                          matlab.ui.control.NumericEditField
        rcdlEditFieldLabel              matlab.ui.control.Label
        jrcdlx                          matlab.ui.control.NumericEditField
        jrcdly                          matlab.ui.control.NumericEditField
        jrcdlz                          matlab.ui.control.NumericEditField
        jbvEditFieldLabel               matlab.ui.control.Label
        jbv                             matlab.ui.control.NumericEditField
        jbcEditFieldLabel               matlab.ui.control.Label
        jbc                             matlab.ui.control.NumericEditField
        mLabel_54                       matlab.ui.control.Label
        mLabel_55                       matlab.ui.control.Label
        mLabel_56                       matlab.ui.control.Label
        mLabel_57                       matlab.ui.control.Label
        mLabel_58                       matlab.ui.control.Label
        mLabel_59                       matlab.ui.control.Label
        inkgm2Label_2                   matlab.ui.control.Label
        inkgm2Label_3                   matlab.ui.control.Label
        kgLabel_3                       matlab.ui.control.Label
        kgm2Label                       matlab.ui.control.Label
        NmALabel                        matlab.ui.control.Label
        NmorNLabel                      matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        mALabel                         matlab.ui.control.Label
        radLabel_23                     matlab.ui.control.Label
        radLabel_24                     matlab.ui.control.Label
        radLabel_25                     matlab.ui.control.Label
        radLabel_26                     matlab.ui.control.Label
        radLabel_27                     matlab.ui.control.Label
        radLabel_28                     matlab.ui.control.Label
        radLabel_29                     matlab.ui.control.Label
        ConnectorSizeLabel              matlab.ui.control.Label
        jconnector                      matlab.ui.control.NumericEditField
        mplEditFieldLabel               matlab.ui.control.Label
        jmpl                            matlab.ui.control.NumericEditField
        mdlEditFieldLabel               matlab.ui.control.Label
        jmdl                            matlab.ui.control.NumericEditField
        ImEditFieldLabel                matlab.ui.control.Label
        Im                              matlab.ui.control.NumericEditField
        AddJoint                        matlab.ui.control.Button
        EndEffectorTab                  matlab.ui.container.Tab
        KinParLabel_3                   matlab.ui.control.Label
        DynParLabel_3                   matlab.ui.control.Label
        kgLabel_4                       matlab.ui.control.Label
        Label_93                        matlab.ui.control.Label
        mLabel_60                       matlab.ui.control.Label
        mLabel_61                       matlab.ui.control.Label
        mLabel_62                       matlab.ui.control.Label
        InertialTensorILabel_2          matlab.ui.control.Label
        inkgm2Label_4                   matlab.ui.control.Label
        aLabel_2                        matlab.ui.control.Label
        ea                              matlab.ui.control.NumericEditField
        Label_94                        matlab.ui.control.Label
        ealpha                          matlab.ui.control.NumericEditField
        pLabel_2                        matlab.ui.control.Label
        ep                              matlab.ui.control.NumericEditField
        nLabel_2                        matlab.ui.control.Label
        en                              matlab.ui.control.NumericEditField
        inLabel_2                       matlab.ui.control.Label
        edeltain                        matlab.ui.control.NumericEditField
        outLabel_2                      matlab.ui.control.Label
        edeltaout                       matlab.ui.control.NumericEditField
        e11                             matlab.ui.control.NumericEditField
        e12                             matlab.ui.control.NumericEditField
        e13                             matlab.ui.control.NumericEditField
        e21                             matlab.ui.control.NumericEditField
        e22                             matlab.ui.control.NumericEditField
        e23                             matlab.ui.control.NumericEditField
        e31                             matlab.ui.control.NumericEditField
        e32                             matlab.ui.control.NumericEditField
        e33                             matlab.ui.control.NumericEditField
        rcLabel_2                       matlab.ui.control.Label
        ercx                            matlab.ui.control.NumericEditField
        ercy                            matlab.ui.control.NumericEditField
        ercz                            matlab.ui.control.NumericEditField
        mLabel_63                       matlab.ui.control.Label
        em                              matlab.ui.control.NumericEditField
        mLabel_64                       matlab.ui.control.Label
        mLabel_65                       matlab.ui.control.Label
        mLabel_66                       matlab.ui.control.Label
        radLabel_30                     matlab.ui.control.Label
        radLabel_31                     matlab.ui.control.Label
        radLabel_32                     matlab.ui.control.Label
        InputConnectorSizeEditFieldLabel_2  matlab.ui.control.Label
        einconnector                    matlab.ui.control.NumericEditField
        AddEE                           matlab.ui.control.Button
        selectortypeActionsLabel        matlab.ui.control.Label
        Action1                         matlab.ui.control.DropDown
        Action2                         matlab.ui.control.DropDown
        Action3                         matlab.ui.control.DropDown
        BaseTab                         matlab.ui.container.Tab
        KinParLabel_4                   matlab.ui.control.Label
        kgLabel_5                       matlab.ui.control.Label
        aLabel_3                        matlab.ui.control.Label
        ba                              matlab.ui.control.NumericEditField
        Label_96                        matlab.ui.control.Label
        balpha                          matlab.ui.control.NumericEditField
        pLabel_3                        matlab.ui.control.Label
        bp                              matlab.ui.control.NumericEditField
        nLabel_3                        matlab.ui.control.Label
        bn                              matlab.ui.control.NumericEditField
        inLabel_3                       matlab.ui.control.Label
        bdeltain                        matlab.ui.control.NumericEditField
        outLabel_3                      matlab.ui.control.Label
        bdeltaout                       matlab.ui.control.NumericEditField
        mLabel_70                       matlab.ui.control.Label
        mLabel_71                       matlab.ui.control.Label
        mLabel_72                       matlab.ui.control.Label
        mLabel_73                       matlab.ui.control.Label
        bm                              matlab.ui.control.NumericEditField
        radLabel_33                     matlab.ui.control.Label
        radLabel_34                     matlab.ui.control.Label
        radLabel_35                     matlab.ui.control.Label
        OutputConnectorSizeLabel_2      matlab.ui.control.Label
        boutconnector                   matlab.ui.control.NumericEditField
        AddBase                         matlab.ui.control.Button
        showmodulelegendButton          matlab.ui.control.Button
        AvailableModulesListBoxLabel    matlab.ui.control.Label
        AvailableModulesListBox         matlab.ui.control.ListBox
        Label_4                         matlab.ui.control.Label
        RemoveButton_4                  matlab.ui.control.Button
        ListBox_2Label                  matlab.ui.control.Label
        MyQuantityListBox               matlab.ui.control.ListBox
        ApplyButton_14                  matlab.ui.control.Button
        LoadMyModules                   matlab.ui.control.Button
        Label_52                        matlab.ui.control.Label
        HelpButton_MM                   matlab.ui.control.Button
        SelectEndEffectorDropDownLabel  matlab.ui.control.Label
        SelectEndEffectorDropDown       matlab.ui.control.DropDown
        Label_2MM                       matlab.ui.control.Label
        PositionsTab                    matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        VREPTab                         matlab.ui.container.Tab
        confirmButton                   matlab.ui.control.Button
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        ZSpinner                        matlab.ui.control.Spinner
        RollSpinnerLabel                matlab.ui.control.Label
        RollSpinner                     matlab.ui.control.Spinner
        YawSpinnerLabel                 matlab.ui.control.Label
        YawSpinner                      matlab.ui.control.Spinner
        PitchSpinnerLabel               matlab.ui.control.Label
        PitchSpinner                    matlab.ui.control.Spinner
        CartesianCoordinatesLabel       matlab.ui.control.Label
        EulerAnglesLabel                matlab.ui.control.Label
        RotaroundXLabel                 matlab.ui.control.Label
        RotaroundYLabel                 matlab.ui.control.Label
        RotaroundZLabel                 matlab.ui.control.Label
        ChooseObstaclesTextAreaLabel    matlab.ui.control.Label
        ChooseObstaclesTextArea         matlab.ui.control.TextArea
        ExploreVrepObstacles            matlab.ui.control.Button
        mmLabel                         matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        gradLabel_3                     matlab.ui.control.Label
        gradLabel                       matlab.ui.control.Label
        gradLabel_2                     matlab.ui.control.Label
        StartVrepButton                 matlab.ui.control.Button
        Label_83                        matlab.ui.control.Label
        Label_169                       matlab.ui.control.Label
        Label_170                       matlab.ui.control.Label
        ManualTab                       matlab.ui.container.Tab
        CartesianCoordinatesLabel_2     matlab.ui.control.Label
        EulerAnglesLabel_2              matlab.ui.control.Label
        confirmButton_manual            matlab.ui.control.Button
        ChooseObstaclesTextArea_2Label  matlab.ui.control.Label
        ChooseObstaclesTextArea_manual  matlab.ui.control.TextArea
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        gradLabel_4                     matlab.ui.control.Label
        gradLabel_5                     matlab.ui.control.Label
        gradLabel_6                     matlab.ui.control.Label
        ExploreVrepObstacles_2          matlab.ui.control.Button
        Label_172                       matlab.ui.control.Label
        Label_173                       matlab.ui.control.Label
        Label_174                       matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        RollEditFieldLabel              matlab.ui.control.Label
        RollEditField                   matlab.ui.control.NumericEditField
        YawEditFieldLabel               matlab.ui.control.Label
        YawEditField                    matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.NumericEditField
        ApplyButton_13                  matlab.ui.control.Button
        ConfirmedPositionsListBox       matlab.ui.control.ListBox
        HelpButton_Pos                  matlab.ui.control.Button
        ConfirmedPositionsLabel         matlab.ui.control.Label
        PositionListBox                 matlab.ui.control.ListBox
        PositionTypeLabel               matlab.ui.control.Label
        RemoveButton_7                  matlab.ui.control.Button
        ResetPositionTypeButton         matlab.ui.control.Button
        Label_81                        matlab.ui.control.Label
        Label_82                        matlab.ui.control.Label
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        AddButton                       matlab.ui.control.Button
        RemoveButton_10                 matlab.ui.control.Button
        Label_171                       matlab.ui.control.Label
        ConfigurationTab_3              matlab.ui.container.Tab
        CalculateConfigurationButton    matlab.ui.control.Button
        BaseEditFieldLabel              matlab.ui.control.Label
        BaseEditField                   matlab.ui.control.EditField
        Joint1EditFieldLabel            matlab.ui.control.Label
        Joint1EditField                 matlab.ui.control.EditField
        Link1EditFieldLabel             matlab.ui.control.Label
        Link1EditField                  matlab.ui.control.EditField
        Joint2EditFieldLabel            matlab.ui.control.Label
        Joint2EditField                 matlab.ui.control.EditField
        Joint4EditFieldLabel            matlab.ui.control.Label
        Joint4EditField                 matlab.ui.control.EditField
        Link3EditFieldLabel             matlab.ui.control.Label
        Link3EditField                  matlab.ui.control.EditField
        Joint3EditFieldLabel            matlab.ui.control.Label
        Joint3EditField                 matlab.ui.control.EditField
        Link2EditFieldLabel             matlab.ui.control.Label
        Link2EditField                  matlab.ui.control.EditField
        Link4EditFieldLabel             matlab.ui.control.Label
        Link4EditField                  matlab.ui.control.EditField
        Joint5EditFieldLabel            matlab.ui.control.Label
        Joint5EditField                 matlab.ui.control.EditField
        Link5EditFieldLabel             matlab.ui.control.Label
        Link5EditField                  matlab.ui.control.EditField
        Joint6EditFieldLabel            matlab.ui.control.Label
        Joint6EditField                 matlab.ui.control.EditField
        Link6EditFieldLabel             matlab.ui.control.Label
        Link6EditField                  matlab.ui.control.EditField
        Link7EditFieldLabel             matlab.ui.control.Label
        Link7EditField                  matlab.ui.control.EditField
        Joint7EditFieldLabel            matlab.ui.control.Label
        Joint7EditField                 matlab.ui.control.EditField
        RobotCompositionLabel           matlab.ui.control.Label
        FinishedAssemblyButton          matlab.ui.control.Button
        AssemblyInstructionButton       matlab.ui.control.Button
        Label_12                        matlab.ui.control.Label
        MonetarySpinnerLabel            matlab.ui.control.Label
        MonetarySpinner                 matlab.ui.control.Spinner
        AssemblySpinnerLabel            matlab.ui.control.Label
        AssemblySpinner                 matlab.ui.control.Spinner
        Joint8EditFieldLabel            matlab.ui.control.Label
        Joint8EditField                 matlab.ui.control.EditField
        Link8EditFieldLabel             matlab.ui.control.Label
        Link8EditField                  matlab.ui.control.EditField
        Joint9EditFieldLabel            matlab.ui.control.Label
        Joint9EditField                 matlab.ui.control.EditField
        Link9EditFieldLabel             matlab.ui.control.Label
        Link9EditField                  matlab.ui.control.EditField
        EndEffectorEditFieldLabel       matlab.ui.control.Label
        EndEffectorEditField            matlab.ui.control.EditField
        ListBoxConfigs                  matlab.ui.control.ListBox
        PayloadLabel                    matlab.ui.control.Label
        MaxPayloadField                 matlab.ui.control.NumericEditField
        KgLabel_4                       matlab.ui.control.Label
        AssEditField                    matlab.ui.control.EditField
        MonEditField                    matlab.ui.control.EditField
        Label_39                        matlab.ui.control.Label
        EnergyConsumptionEditFieldLabel  matlab.ui.control.Label
        EnergyConsumptionEditField      matlab.ui.control.EditField
        TaskTimeEditFieldLabel          matlab.ui.control.Label
        TaskTimeEditField               matlab.ui.control.EditField
        AssemblyEditFieldLabel          matlab.ui.control.Label
        AssemblyEditField               matlab.ui.control.NumericEditField
        ModuleshavetobechangedLabel     matlab.ui.control.Label
        MonetaryEditFieldLabel          matlab.ui.control.Label
        MonetaryEditField               matlab.ui.control.NumericEditField
        BuyLabel                        matlab.ui.control.Label
        NewModulesLabel                 matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        Label_60                        matlab.ui.control.Label
        Label_61                        matlab.ui.control.Label
        Label_62                        matlab.ui.control.Label
        HelpButton_Conifg               matlab.ui.control.Button
        StopCalculatingButton           matlab.ui.control.StateButton
        TaskTimeSliderLabel             matlab.ui.control.Label
        TaskTimeSlider                  matlab.ui.control.Slider
        EnergyConsumptionLabel          matlab.ui.control.Label
        Label_141                       matlab.ui.control.Label
        Label_142                       matlab.ui.control.Label
        Label_143                       matlab.ui.control.Label
        Label_144                       matlab.ui.control.Label
        Label_145                       matlab.ui.control.Label
        Label_146                       matlab.ui.control.Label
        Label_147                       matlab.ui.control.Label
        Label_148                       matlab.ui.control.Label
        Label_149                       matlab.ui.control.Label
        Label_150                       matlab.ui.control.Label
        Label_151                       matlab.ui.control.Label
        Label_152                       matlab.ui.control.Label
        Label_153                       matlab.ui.control.Label
        Label_154                       matlab.ui.control.Label
        Label_155                       matlab.ui.control.Label
        Label_156                       matlab.ui.control.Label
        Label_157                       matlab.ui.control.Label
        Label_158                       matlab.ui.control.Label
        Label_159                       matlab.ui.control.Label
        Label_160                       matlab.ui.control.Label
        Label_161                       matlab.ui.control.Label
        Label_162                       matlab.ui.control.Label
        Label_163                       matlab.ui.control.Label
        Label_164                       matlab.ui.control.Label
        Label_165                       matlab.ui.control.Label
        Label_166                       matlab.ui.control.Label
        Label_167                       matlab.ui.control.Label
        Label_168                       matlab.ui.control.Label
        CalibrationTab                  matlab.ui.container.Tab
        AutomaticMoveButton             matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        ConfirmCalibratedPositionButton  matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        TaskSpaceTab                    matlab.ui.container.Tab
        EndeffectorSpeedEditFieldLabel  matlab.ui.control.Label
        EndeffectorSpeedEditField       matlab.ui.control.NumericEditField
        SetButtonTaskSpace              matlab.ui.control.Button
        msLabel_2                       matlab.ui.control.Label
        Label_99                        matlab.ui.control.Label
        JointSpaceTab                   matlab.ui.container.Tab
        SelectJointListBoxLabel         matlab.ui.control.Label
        SelectJointListBox              matlab.ui.control.ListBox
        JointSpeedEditFieldLabel        matlab.ui.control.Label
        JointSpeedEditField             matlab.ui.control.NumericEditField
        SetButtonJointSpace             matlab.ui.control.Button
        Label_86                        matlab.ui.control.Label
        radsLabel                       matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        ApplyConfiguration              matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        Label_65                        matlab.ui.control.Label
        Label_66                        matlab.ui.control.Label
        Label_67                        matlab.ui.control.Label
        HelpButton_Cal                  matlab.ui.control.Button
        CalibratedPositionsThistabLabel  matlab.ui.control.Label
        ListBox_3Label                  matlab.ui.control.Label
        calPosCalTab                    matlab.ui.control.ListBox
        RemoveCalibratedPositionButton  matlab.ui.control.Button
        Label_87                        matlab.ui.control.Label
        Label_88                        matlab.ui.control.Label
        Label_89                        matlab.ui.control.Label
        PositionListBoxCalTab           matlab.ui.control.ListBox
        PositionstocalibrateLabel       matlab.ui.control.Label
        TeachingTab                     matlab.ui.container.Tab
        ResetSeq                        matlab.ui.control.Button
        Label_33                        matlab.ui.control.Label
        AvailablePostionsLabel          matlab.ui.control.Label
        AvailablePostionsListBox        matlab.ui.control.ListBox
        SequenceListBoxLabel            matlab.ui.control.Label
        SequenceListBox                 matlab.ui.control.ListBox
        AvailableActionsLabel           matlab.ui.control.Label
        AvailableActionsListBox         matlab.ui.control.ListBox
        TypeofMovementLabel             matlab.ui.control.Label
        TypeofMovementListBox           matlab.ui.control.ListBox
        FollowSequenceButton            matlab.ui.control.Button
        ConfirmSequenceButton           matlab.ui.control.Button
        WaitTimeEditFieldLabel          matlab.ui.control.Label
        WaitTimeEditField               matlab.ui.control.NumericEditField
        secondsLabel                    matlab.ui.control.Label
        AddDelayToSeq                   matlab.ui.control.Button
        AddSignalToSeq                  matlab.ui.control.Button
        AvailableSignalsListBoxLabel    matlab.ui.control.Label
        AvailableSignalsListBox         matlab.ui.control.ListBox
        SequenceNameEditFieldLabel      matlab.ui.control.Label
        SequenceNameEditField           matlab.ui.control.EditField
        DefinedSequencesLabel           matlab.ui.control.Label
        DefinedSequencesListBox         matlab.ui.control.ListBox
        Label_38                        matlab.ui.control.Label
        AddPositionButton               matlab.ui.control.Button
        RemoveButton_8                  matlab.ui.control.Button
        AddActionButton                 matlab.ui.control.Button
        ExpertModeCheckBox              matlab.ui.control.CheckBox
        RemoveButton_9                  matlab.ui.control.Button
        LoadSequencesButton             matlab.ui.control.Button
        ApplyButton_15                  matlab.ui.control.Button
        Label_68                        matlab.ui.control.Label
        Label_69                        matlab.ui.control.Label
        Label_70                        matlab.ui.control.Label
        Label_71                        matlab.ui.control.Label
        HelpButton_teach                matlab.ui.control.Button
        STOPteaching                    matlab.ui.control.Button
        VelocityEditFieldLabel          matlab.ui.control.Label
        VelocityTrajectory              matlab.ui.control.NumericEditField
        msLabel_4                       matlab.ui.control.Label
        GravityCompensationSwitchLabel  matlab.ui.control.Label
        GravityCompensationSwitch       matlab.ui.control.ToggleSwitch
        NameEditField_2Label            matlab.ui.control.Label
        GCname                          matlab.ui.control.EditField
        DesiredValueEditFieldLabel      matlab.ui.control.Label
        DesiredValueEditField           matlab.ui.control.EditField
        SignalOutIn                     matlab.ui.control.ToggleSwitch
        ModulesdefinedLabel             matlab.ui.control.Label
        Lamp_2Label                     matlab.ui.control.Label
        Lamp_M                          matlab.ui.control.Lamp
        PositionsdefinedLabel           matlab.ui.control.Label
        Lamp_3Label                     matlab.ui.control.Label
        Lamp_P                          matlab.ui.control.Lamp
        RobotassembledLabel             matlab.ui.control.Label
        Lamp_4Label                     matlab.ui.control.Label
        Lamp_A                          matlab.ui.control.Lamp
        RobotcalibratedLabel            matlab.ui.control.Label
        Lamp_5Label                     matlab.ui.control.Label
        Lamp_C                          matlab.ui.control.Lamp
        RobotteachingLabel              matlab.ui.control.Label
        Lamp_T                          matlab.ui.control.Lamp
        ReadyLabel_3                    matlab.ui.control.Label
        Lamp_R                          matlab.ui.control.Lamp
        WorkflowLabel                   matlab.ui.control.Label
        StateTextAreaLabel              matlab.ui.control.Label
        State                           matlab.ui.control.TextArea
    end


    methods (Access = private)

        % Button pushed function: HelpButton_Pos
        function HelpButton_PosPushed(app, event)
            winopen(strcat(pwd,'\user-interface-report.pdf'));
        end

        % Button pushed function: HelpButton_Conifg
        function HelpButton_ConifgPushed(app, event)
            winopen(strcat(pwd,'\user-interface-report.pdf'));
        end

        % Button pushed function: HelpButton_Cal
        function HelpButton_CalPushed(app, event)
            winopen(strcat(pwd,'\user-interface-report.pdf'));
        end

        % Button pushed function: HelpButton_teach
        function HelpButton_teachPushed(app, event)
            winopen(strcat(pwd,'\user-interface-report.pdf'));
        end

        % Button pushed function: HelpButton_Main
        function HelpButton_MainPushed(app, event)
            winopen(strcat(pwd,'\user-interface-report.pdf'));
        end

        % Button pushed function: HelpButton_MM
        function HelpButton_MMPushed(app, event)
            winopen(strcat(pwd,'\user-interface-report.pdf'));
        end

        % Button pushed function: ReadReportButton
        function ReadReportButtonPushed(app, event)
            winopen(strcat(pwd,'\PDFs\user-interface-report.pdf'));
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value(2) =='n' %On
                app.Lamp_R.Color='g';
                %app.State.Value=statemes(17);
                app.State.BackgroundColor='g';
                app.Work.Enable='On';
            else %Off
                app.Work.Enable='Off';
                app.Lamp_R.Color='r';
                %app.State.Value=statemes(16);
                app.State.BackgroundColor='r';
            end
        end

        % Button pushed function: ExploreVrepObstacles
        function ExploreVrepObstaclesButtonPushed(app, event)
            [file,path] = uigetfile('*.STL', 'Provide a Work Cell as a STL file');
        end

        % Value changed function: AssemblySpinner
        function AssemblySpinnerValueChanged(app, event)
            value = app.AssemblySpinner.Value
            %app.AssEditField.Value = num2crit(value,'A');
        end

        % Value changed function: MonetarySpinner
        function MonetarySpinnerValueChanged(app, event)
            value = app.MonetarySpinner.Value;
            %app.MonEditField.Value = num2crit(value,'M');
        end

        % Value changed function: ExpertModeCheckBox
        function ExpertModeCheckBoxValueChanged(app, event)
            value = app.ExpertModeCheckBox.Value;
            
            % on expert mode
            if value==1
                app.TypeofMovementListBox.Enable='on';
                app.AvailableSignalsListBox.Enable='on';
                app.DesiredValueEditField.Enable='on';
                app.AddSignalToSeq.Enable='on';
                app.SignalOutIn.Enable='on';
                
            else
                app.TypeofMovementListBox.Enable='off';
                app.AvailableSignalsListBox.Enable='off';
                app.DesiredValueEditField.Enable='off';
                app.AddSignalToSeq.Enable='off';
                app.SignalOutIn.Enable='off';
                
                app.TypeofMovementListBox.Value='PTP';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RobertPanel = uipanel(app.UIFigure);
            app.RobertPanel.Title = 'Robert';
            app.TabGroup = uitabgroup(app.RobertPanel);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.Work = uibutton(app.MainTab, 'push');
            app.Work.Text = 'Work';
            app.Label = uilabel(app.MainTab);
            app.Label.Text = '';
            app.Switch = uiswitch(app.MainTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.STOPwork = uibutton(app.MainTab, 'push');
            app.STOPwork.Enable = 'off';
            app.STOPwork.Text = 'STOP';
            app.Label_3 = uilabel(app.MainTab);
            app.Label_3.Text = {'Here you can control the robot, if the lamp "ready" on the left is green. Before you have to select your available'; 'modules, set the positions, and confirm the positions. Furthermore, you need to assemble the robot according '; 'to the suggested configuration and test if all positions are correct (calibration). Read the manual here for'; 'further advice.'};
            app.ReadReportButton = uibutton(app.MainTab, 'push');
            app.ReadReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReadReportButtonPushed, true);
            app.ReadReportButton.Text = 'Read Report';
            app.LoadSettingsButton = uibutton(app.MainTab, 'push');
            app.LoadSettingsButton.Text = 'Load Settings';
            app.ChooseSequenceListBoxLabel = uilabel(app.MainTab);
            app.ChooseSequenceListBoxLabel.Text = 'Choose Sequence';
            app.ChooseSequenceListBox = uilistbox(app.MainTab);
            app.ChooseSequenceListBox.Items = {};
            app.ChooseSequenceListBox.Enable = 'off';
            app.ChooseSequenceListBox.Value = {};
            app.Label_48 = uilabel(app.MainTab);
            app.Label_48.Text = '2';
            app.Label_49 = uilabel(app.MainTab);
            app.Label_49.Text = '1';
            app.Label_50 = uilabel(app.MainTab);
            app.Label_50.Text = '3';
            app.HelpButton_Main = uibutton(app.MainTab, 'push');
            app.HelpButton_Main.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_MainPushed, true);
            app.HelpButton_Main.Text = 'Help';
            app.ModuleManagementTab = uitab(app.TabGroup);
            app.ModuleManagementTab.Title = 'Module Management';
            app.AddNewModulePanel = uipanel(app.ModuleManagementTab);
            app.AddNewModulePanel.Title = 'Add New Module';
            app.TabGroup5 = uitabgroup(app.AddNewModulePanel);
            app.CatalogTab = uitab(app.TabGroup5);
            app.CatalogTab.Title = 'Catalog';
            app.AddToMyModulesFromCat = uibutton(app.CatalogTab, 'push');
            app.AddToMyModulesFromCat.Text = 'Add to Available Modules';
            app.BuyableModulesLabel = uilabel(app.CatalogTab);
            app.BuyableModulesLabel.Text = 'Buyable Modules';
            app.CatalogueModulesListBox = uilistbox(app.CatalogTab);
            app.CatalogueModulesListBox.Items = {};
            app.CatalogueModulesListBox.Value = {};
            app.AddToMyModulesFromOwn = uibutton(app.CatalogTab, 'push');
            app.AddToMyModulesFromOwn.Text = 'Add to Available Modules';
            app.RemoveSelectedModuleButton = uibutton(app.CatalogTab, 'push');
            app.RemoveSelectedModuleButton.Text = 'Remove Selected Module';
            app.Label_15 = uilabel(app.CatalogTab);
            app.Label_15.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_53 = uilabel(app.CatalogTab);
            app.Label_53.Text = '1';
            app.UserdefinedModulesListBoxLabel = uilabel(app.CatalogTab);
            app.UserdefinedModulesListBoxLabel.Text = 'User-defined Modules';
            app.UserdefinedModulesListBox = uilistbox(app.CatalogTab);
            app.UserdefinedModulesListBox.Items = {};
            app.UserdefinedModulesListBox.Value = {};
            app.OwnModulesTab = uitab(app.TabGroup5);
            app.OwnModulesTab.Title = 'Own Modules';
            app.NameEditField_3Label = uilabel(app.OwnModulesTab);
            app.NameEditField_3Label.Text = 'Name';
            app.NameOwnModule = uieditfield(app.OwnModulesTab, 'text');
            app.NameOwnModule.Value = 'MyModule1';
            app.ExploreButton_3 = uibutton(app.OwnModulesTab, 'push');
            app.ExploreButton_3.Text = 'Explore';
            app.STLEditFieldLabel = uilabel(app.OwnModulesTab);
            app.STLEditFieldLabel.Text = 'STL';
            app.STLTextArea = uieditfield(app.OwnModulesTab, 'text');
            app.STLTextArea.Enable = 'off';
            app.TabGroup2 = uitabgroup(app.OwnModulesTab);
            app.LinkTab = uitab(app.TabGroup2);
            app.LinkTab.Title = 'Link';
            app.KinParLabel = uilabel(app.LinkTab);
            app.KinParLabel.Text = 'KinPar';
            app.DynParLabel = uilabel(app.LinkTab);
            app.DynParLabel.Text = 'DynPar';
            app.kgLabel = uilabel(app.LinkTab);
            app.kgLabel.Text = 'kg';
            app.Label_90 = uilabel(app.LinkTab);
            app.Label_90.Text = '______________________________________________________________________________';
            app.mLabel_41 = uilabel(app.LinkTab);
            app.mLabel_41.Text = 'm';
            app.mLabel_42 = uilabel(app.LinkTab);
            app.mLabel_42.Text = 'm';
            app.mLabel_43 = uilabel(app.LinkTab);
            app.mLabel_43.Text = 'm';
            app.aLabel = uilabel(app.LinkTab);
            app.aLabel.Text = 'a';
            app.la = uieditfield(app.LinkTab, 'numeric');
            app.Label_91 = uilabel(app.LinkTab);
            app.Label_91.Text = 'α';
            app.lalpha = uieditfield(app.LinkTab, 'numeric');
            app.pLabel = uilabel(app.LinkTab);
            app.pLabel.Text = 'p';
            app.lp = uieditfield(app.LinkTab, 'numeric');
            app.nLabel = uilabel(app.LinkTab);
            app.nLabel.Text = 'n';
            app.ln = uieditfield(app.LinkTab, 'numeric');
            app.inLabel = uilabel(app.LinkTab);
            app.inLabel.Text = 'δin';
            app.ldeltain = uieditfield(app.LinkTab, 'numeric');
            app.outLabel = uilabel(app.LinkTab);
            app.outLabel.Text = 'δout';
            app.ldeltaout = uieditfield(app.LinkTab, 'numeric');
            app.InertialTensorILabel = uilabel(app.LinkTab);
            app.InertialTensorILabel.Text = 'Inertial Tensor I';
            app.inkgm2Label = uilabel(app.LinkTab);
            app.inkgm2Label.Text = 'in kg*m^2';
            app.I11 = uieditfield(app.LinkTab, 'numeric');
            app.I11.ValueDisplayFormat = '%11.7g';
            app.I12 = uieditfield(app.LinkTab, 'numeric');
            app.I12.ValueDisplayFormat = '%11.7g';
            app.I13 = uieditfield(app.LinkTab, 'numeric');
            app.I13.ValueDisplayFormat = '%11.7g';
            app.I21 = uieditfield(app.LinkTab, 'numeric');
            app.I21.ValueDisplayFormat = '%11.7g';
            app.I22 = uieditfield(app.LinkTab, 'numeric');
            app.I22.ValueDisplayFormat = '%11.7g';
            app.I23 = uieditfield(app.LinkTab, 'numeric');
            app.I23.ValueDisplayFormat = '%11.7g';
            app.I31 = uieditfield(app.LinkTab, 'numeric');
            app.I31.ValueDisplayFormat = '%11.7g';
            app.I32 = uieditfield(app.LinkTab, 'numeric');
            app.I32.ValueDisplayFormat = '%11.7g';
            app.I33 = uieditfield(app.LinkTab, 'numeric');
            app.I33.ValueDisplayFormat = '%11.7g';
            app.rcLabel = uilabel(app.LinkTab);
            app.rcLabel.Text = 'rc';
            app.lrcx = uieditfield(app.LinkTab, 'numeric');
            app.lrcy = uieditfield(app.LinkTab, 'numeric');
            app.lrcz = uieditfield(app.LinkTab, 'numeric');
            app.mLabel_44 = uilabel(app.LinkTab);
            app.mLabel_44.Text = 'm';
            app.mLabel_45 = uilabel(app.LinkTab);
            app.mLabel_45.Text = 'm';
            app.mLabel_46 = uilabel(app.LinkTab);
            app.mLabel_46.Text = 'm';
            app.radLabel_20 = uilabel(app.LinkTab);
            app.radLabel_20.Text = ' rad';
            app.radLabel_21 = uilabel(app.LinkTab);
            app.radLabel_21.Text = ' rad';
            app.radLabel_22 = uilabel(app.LinkTab);
            app.radLabel_22.Text = ' rad';
            app.mLabel_47 = uilabel(app.LinkTab);
            app.mLabel_47.Text = 'm';
            app.lm = uieditfield(app.LinkTab, 'numeric');
            app.lm.Limits = [0 Inf];
            app.InputConnectorSizeEditFieldLabel = uilabel(app.LinkTab);
            app.InputConnectorSizeEditFieldLabel.Text = 'InputConnectorSize';
            app.linconnector = uieditfield(app.LinkTab, 'numeric');
            app.linconnector.Limits = [1 2];
            app.linconnector.ValueDisplayFormat = '%.0f';
            app.linconnector.Value = 1;
            app.OutputConnectorSizeLabel = uilabel(app.LinkTab);
            app.OutputConnectorSizeLabel.Text = 'OutputConnectorSize';
            app.loutconnector = uieditfield(app.LinkTab, 'numeric');
            app.loutconnector.Limits = [1 2];
            app.loutconnector.ValueDisplayFormat = '%.0f';
            app.loutconnector.Value = 1;
            app.AddLink = uibutton(app.LinkTab, 'push');
            app.AddLink.Text = 'Add To Catalog';
            app.JointTab = uitab(app.TabGroup2);
            app.JointTab.Title = 'Joint';
            app.KinParLabel_2 = uilabel(app.JointTab);
            app.KinParLabel_2.Text = 'KinPar';
            app.DynParLabel_2 = uilabel(app.JointTab);
            app.DynParLabel_2.Text = 'DynPar';
            app.kgLabel_2 = uilabel(app.JointTab);
            app.kgLabel_2.Text = 'kg ';
            app.Label_92 = uilabel(app.JointTab);
            app.Label_92.Text = '______________________________________________________________________________';
            app.mLabel_48 = uilabel(app.JointTab);
            app.mLabel_48.Text = 'm';
            app.mLabel_49 = uilabel(app.JointTab);
            app.mLabel_49.Text = 'm';
            app.mLabel_50 = uilabel(app.JointTab);
            app.mLabel_50.Text = 'm';
            app.mLabel_51 = uilabel(app.JointTab);
            app.mLabel_51.Text = 'm';
            app.mLabel_52 = uilabel(app.JointTab);
            app.mLabel_52.Text = 'm';
            app.mLabel_53 = uilabel(app.JointTab);
            app.mLabel_53.Text = 'm';
            app.jtLabel = uilabel(app.JointTab);
            app.jtLabel.Text = 'jt';
            app.jt = uieditfield(app.JointTab, 'numeric');
            app.jt.Limits = [0 1];
            app.jt.ValueDisplayFormat = '%11.1g';
            app.LjlLabel = uilabel(app.JointTab);
            app.LjlLabel.Text = 'Ljl';
            app.ljl = uieditfield(app.JointTab, 'numeric');
            app.UjlLabel = uilabel(app.JointTab);
            app.UjlLabel.Text = 'Ujl';
            app.ujl = uieditfield(app.JointTab, 'numeric');
            app.jLabel = uilabel(app.JointTab);
            app.jLabel.Text = 'δj';
            app.jdeltaj = uieditfield(app.JointTab, 'numeric');
            app.aplEditFieldLabel = uilabel(app.JointTab);
            app.aplEditFieldLabel.Text = 'apl';
            app.japl = uieditfield(app.JointTab, 'numeric');
            app.plEditFieldLabel = uilabel(app.JointTab);
            app.plEditFieldLabel.Text = 'αpl';
            app.jalphapl = uieditfield(app.JointTab, 'numeric');
            app.pplEditFieldLabel = uilabel(app.JointTab);
            app.pplEditFieldLabel.Text = 'ppl';
            app.jppl = uieditfield(app.JointTab, 'numeric');
            app.nplEditFieldLabel = uilabel(app.JointTab);
            app.nplEditFieldLabel.Text = 'npl';
            app.jnpl = uieditfield(app.JointTab, 'numeric');
            app.plEditField_2Label = uilabel(app.JointTab);
            app.plEditField_2Label.Text = 'δpl';
            app.jdeltapl = uieditfield(app.JointTab, 'numeric');
            app.adlEditFieldLabel = uilabel(app.JointTab);
            app.adlEditFieldLabel.Text = 'adl';
            app.jadl = uieditfield(app.JointTab, 'numeric');
            app.dlEditFieldLabel = uilabel(app.JointTab);
            app.dlEditFieldLabel.Text = 'αdl';
            app.jalphadl = uieditfield(app.JointTab, 'numeric');
            app.pdlLabel = uilabel(app.JointTab);
            app.pdlLabel.Text = 'pdl';
            app.jpdl = uieditfield(app.JointTab, 'numeric');
            app.ndlEditFieldLabel = uilabel(app.JointTab);
            app.ndlEditFieldLabel.Text = 'ndl';
            app.jndl = uieditfield(app.JointTab, 'numeric');
            app.dlEditField_2Label = uilabel(app.JointTab);
            app.dlEditField_2Label.Text = 'δdl';
            app.jdeltadl = uieditfield(app.JointTab, 'numeric');
            app.kEditFieldLabel = uilabel(app.JointTab);
            app.kEditFieldLabel.Text = 'kτ';
            app.ktau = uieditfield(app.JointTab, 'numeric');
            app.krEditFieldLabel = uilabel(app.JointTab);
            app.krEditFieldLabel.Text = 'kr';
            app.kr = uieditfield(app.JointTab, 'numeric');
            app.IplEditFieldLabel = uilabel(app.JointTab);
            app.IplEditFieldLabel.Text = 'Ipl';
            app.jIpl11 = uieditfield(app.JointTab, 'numeric');
            app.jIpl11.ValueDisplayFormat = '%11.9g';
            app.jIpl12 = uieditfield(app.JointTab, 'numeric');
            app.jIpl12.ValueDisplayFormat = '%11.9g';
            app.jIpl13 = uieditfield(app.JointTab, 'numeric');
            app.jIpl13.ValueDisplayFormat = '%11.9g';
            app.jIpl22 = uieditfield(app.JointTab, 'numeric');
            app.jIpl22.ValueDisplayFormat = '%11.9g';
            app.jIpl23 = uieditfield(app.JointTab, 'numeric');
            app.jIpl23.ValueDisplayFormat = '%11.9g';
            app.jIpl33 = uieditfield(app.JointTab, 'numeric');
            app.jIpl33.ValueDisplayFormat = '%11.9g';
            app.rcplEditFieldLabel = uilabel(app.JointTab);
            app.rcplEditFieldLabel.Text = 'rcpl';
            app.jrcplx = uieditfield(app.JointTab, 'numeric');
            app.jrcply = uieditfield(app.JointTab, 'numeric');
            app.jrcplz = uieditfield(app.JointTab, 'numeric');
            app.limEditFieldLabel = uilabel(app.JointTab);
            app.limEditFieldLabel.Text = 'τlim';
            app.taulim = uieditfield(app.JointTab, 'numeric');
            app.dqlimEditFieldLabel = uilabel(app.JointTab);
            app.dqlimEditFieldLabel.Text = 'dqlim';
            app.dqlim = uieditfield(app.JointTab, 'numeric');
            app.ddqlimEditFieldLabel = uilabel(app.JointTab);
            app.ddqlimEditFieldLabel.Text = 'ddqlim';
            app.ddqlim = uieditfield(app.JointTab, 'numeric');
            app.curlimEditFieldLabel = uilabel(app.JointTab);
            app.curlimEditFieldLabel.Text = 'curlim';
            app.curlim = uieditfield(app.JointTab, 'numeric');
            app.IdlLabel = uilabel(app.JointTab);
            app.IdlLabel.Text = 'Idl';
            app.jIdl11 = uieditfield(app.JointTab, 'numeric');
            app.jIdl11.ValueDisplayFormat = '%11.9g';
            app.jIdl12 = uieditfield(app.JointTab, 'numeric');
            app.jIdl12.ValueDisplayFormat = '%11.9g';
            app.jIdl13 = uieditfield(app.JointTab, 'numeric');
            app.jIdl13.ValueDisplayFormat = '%11.9g';
            app.jIdl22 = uieditfield(app.JointTab, 'numeric');
            app.jIdl22.ValueDisplayFormat = '%11.9g';
            app.jIdl23 = uieditfield(app.JointTab, 'numeric');
            app.jIdl23.ValueDisplayFormat = '%11.9g';
            app.jIdl33 = uieditfield(app.JointTab, 'numeric');
            app.jIdl33.ValueDisplayFormat = '%11.9g';
            app.rcdlEditFieldLabel = uilabel(app.JointTab);
            app.rcdlEditFieldLabel.Text = 'rcdl';
            app.jrcdlx = uieditfield(app.JointTab, 'numeric');
            app.jrcdly = uieditfield(app.JointTab, 'numeric');
            app.jrcdlz = uieditfield(app.JointTab, 'numeric');
            app.jbvEditFieldLabel = uilabel(app.JointTab);
            app.jbvEditFieldLabel.Text = 'jbv';
            app.jbv = uieditfield(app.JointTab, 'numeric');
            app.jbcEditFieldLabel = uilabel(app.JointTab);
            app.jbcEditFieldLabel.Text = 'jbc';
            app.jbc = uieditfield(app.JointTab, 'numeric');
            app.mLabel_54 = uilabel(app.JointTab);
            app.mLabel_54.Text = 'm';
            app.mLabel_55 = uilabel(app.JointTab);
            app.mLabel_55.Text = 'm';
            app.mLabel_56 = uilabel(app.JointTab);
            app.mLabel_56.Text = 'm';
            app.mLabel_57 = uilabel(app.JointTab);
            app.mLabel_57.Text = 'm';
            app.mLabel_58 = uilabel(app.JointTab);
            app.mLabel_58.Text = 'm';
            app.mLabel_59 = uilabel(app.JointTab);
            app.mLabel_59.Text = 'm';
            app.inkgm2Label_2 = uilabel(app.JointTab);
            app.inkgm2Label_2.Text = 'in kg*m^2';
            app.inkgm2Label_3 = uilabel(app.JointTab);
            app.inkgm2Label_3.Text = 'in kg*m^2';
            app.kgLabel_3 = uilabel(app.JointTab);
            app.kgLabel_3.Text = 'kg ';
            app.kgm2Label = uilabel(app.JointTab);
            app.kgm2Label.Text = ' kg*m^2';
            app.NmALabel = uilabel(app.JointTab);
            app.NmALabel.Text = ' Nm/A';
            app.NmorNLabel = uilabel(app.JointTab);
            app.NmorNLabel.Text = ' Nm or N';
            app.msLabel = uilabel(app.JointTab);
            app.msLabel.Text = ' m/s';
            app.ms2Label = uilabel(app.JointTab);
            app.ms2Label.Text = ' m/s^2';
            app.mALabel = uilabel(app.JointTab);
            app.mALabel.Text = ' mA';
            app.radLabel_23 = uilabel(app.JointTab);
            app.radLabel_23.Text = ' rad';
            app.radLabel_24 = uilabel(app.JointTab);
            app.radLabel_24.Text = ' rad';
            app.radLabel_25 = uilabel(app.JointTab);
            app.radLabel_25.Text = ' rad';
            app.radLabel_26 = uilabel(app.JointTab);
            app.radLabel_26.Text = ' rad';
            app.radLabel_27 = uilabel(app.JointTab);
            app.radLabel_27.Text = ' rad';
            app.radLabel_28 = uilabel(app.JointTab);
            app.radLabel_28.Text = ' rad';
            app.radLabel_29 = uilabel(app.JointTab);
            app.radLabel_29.Text = ' rad';
            app.ConnectorSizeLabel = uilabel(app.JointTab);
            app.ConnectorSizeLabel.Text = 'ConnectorSize';
            app.jconnector = uieditfield(app.JointTab, 'numeric');
            app.jconnector.Limits = [1 2];
            app.jconnector.ValueDisplayFormat = '%.0f';
            app.jconnector.Value = 1;
            app.mplEditFieldLabel = uilabel(app.JointTab);
            app.mplEditFieldLabel.Text = 'mpl';
            app.jmpl = uieditfield(app.JointTab, 'numeric');
            app.jmpl.Limits = [0 Inf];
            app.mdlEditFieldLabel = uilabel(app.JointTab);
            app.mdlEditFieldLabel.Text = 'mdl';
            app.jmdl = uieditfield(app.JointTab, 'numeric');
            app.jmdl.Limits = [0 Inf];
            app.ImEditFieldLabel = uilabel(app.JointTab);
            app.ImEditFieldLabel.Text = 'Im';
            app.Im = uieditfield(app.JointTab, 'numeric');
            app.AddJoint = uibutton(app.JointTab, 'push');
            app.AddJoint.Text = 'Add to Catalog';
            app.EndEffectorTab = uitab(app.TabGroup2);
            app.EndEffectorTab.Title = 'End Effector';
            app.KinParLabel_3 = uilabel(app.EndEffectorTab);
            app.KinParLabel_3.Text = 'KinPar';
            app.DynParLabel_3 = uilabel(app.EndEffectorTab);
            app.DynParLabel_3.Text = 'DynPar';
            app.kgLabel_4 = uilabel(app.EndEffectorTab);
            app.kgLabel_4.Text = 'kg';
            app.Label_93 = uilabel(app.EndEffectorTab);
            app.Label_93.Text = '______________________________________________________________________________';
            app.mLabel_60 = uilabel(app.EndEffectorTab);
            app.mLabel_60.Text = 'm';
            app.mLabel_61 = uilabel(app.EndEffectorTab);
            app.mLabel_61.Text = 'm';
            app.mLabel_62 = uilabel(app.EndEffectorTab);
            app.mLabel_62.Text = 'm';
            app.InertialTensorILabel_2 = uilabel(app.EndEffectorTab);
            app.InertialTensorILabel_2.Text = 'Inertial Tensor I';
            app.inkgm2Label_4 = uilabel(app.EndEffectorTab);
            app.inkgm2Label_4.Text = 'in kg*m^2';
            app.aLabel_2 = uilabel(app.EndEffectorTab);
            app.aLabel_2.Text = 'a';
            app.ea = uieditfield(app.EndEffectorTab, 'numeric');
            app.Label_94 = uilabel(app.EndEffectorTab);
            app.Label_94.Text = 'α';
            app.ealpha = uieditfield(app.EndEffectorTab, 'numeric');
            app.pLabel_2 = uilabel(app.EndEffectorTab);
            app.pLabel_2.Text = 'p';
            app.ep = uieditfield(app.EndEffectorTab, 'numeric');
            app.nLabel_2 = uilabel(app.EndEffectorTab);
            app.nLabel_2.Text = 'n';
            app.en = uieditfield(app.EndEffectorTab, 'numeric');
            app.inLabel_2 = uilabel(app.EndEffectorTab);
            app.inLabel_2.Text = 'δin';
            app.edeltain = uieditfield(app.EndEffectorTab, 'numeric');
            app.outLabel_2 = uilabel(app.EndEffectorTab);
            app.outLabel_2.Text = 'δout';
            app.edeltaout = uieditfield(app.EndEffectorTab, 'numeric');
            app.e11 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e11.ValueDisplayFormat = '%11.7g';
            app.e12 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e12.ValueDisplayFormat = '%11.7g';
            app.e13 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e13.ValueDisplayFormat = '%11.7g';
            app.e21 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e21.ValueDisplayFormat = '%11.7g';
            app.e22 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e22.ValueDisplayFormat = '%11.7g';
            app.e23 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e23.ValueDisplayFormat = '%11.7g';
            app.e31 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e31.ValueDisplayFormat = '%11.7g';
            app.e32 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e32.ValueDisplayFormat = '%11.7g';
            app.e33 = uieditfield(app.EndEffectorTab, 'numeric');
            app.e33.ValueDisplayFormat = '%11.7g';
            app.rcLabel_2 = uilabel(app.EndEffectorTab);
            app.rcLabel_2.Text = 'rc';
            app.ercx = uieditfield(app.EndEffectorTab, 'numeric');
            app.ercy = uieditfield(app.EndEffectorTab, 'numeric');
            app.ercz = uieditfield(app.EndEffectorTab, 'numeric');
            app.mLabel_63 = uilabel(app.EndEffectorTab);
            app.mLabel_63.Text = 'm';
            app.em = uieditfield(app.EndEffectorTab, 'numeric');
            app.em.Limits = [0 Inf];
            app.mLabel_64 = uilabel(app.EndEffectorTab);
            app.mLabel_64.Text = 'm';
            app.mLabel_65 = uilabel(app.EndEffectorTab);
            app.mLabel_65.Text = 'm';
            app.mLabel_66 = uilabel(app.EndEffectorTab);
            app.mLabel_66.Text = 'm';
            app.radLabel_30 = uilabel(app.EndEffectorTab);
            app.radLabel_30.Text = ' rad';
            app.radLabel_31 = uilabel(app.EndEffectorTab);
            app.radLabel_31.Text = ' rad';
            app.radLabel_32 = uilabel(app.EndEffectorTab);
            app.radLabel_32.Text = ' rad';
            app.InputConnectorSizeEditFieldLabel_2 = uilabel(app.EndEffectorTab);
            app.InputConnectorSizeEditFieldLabel_2.Text = 'InputConnectorSize';
            app.einconnector = uieditfield(app.EndEffectorTab, 'numeric');
            app.einconnector.Limits = [1 2];
            app.einconnector.ValueDisplayFormat = '%.0f';
            app.einconnector.Value = 1;
            app.AddEE = uibutton(app.EndEffectorTab, 'push');
            app.AddEE.Text = 'Add To Catalog';
            app.selectortypeActionsLabel = uilabel(app.EndEffectorTab);
            app.selectortypeActionsLabel.Text = 'select or type Actions';
            app.Action1 = uidropdown(app.EndEffectorTab);
            app.Action1.Items = {'No Action', 'Pick', 'Place', 'Drill', 'Cut', 'Off', 'On'};
            app.Action1.Editable = 'on';
            app.Action1.Value = 'No Action';
            app.Action2 = uidropdown(app.EndEffectorTab);
            app.Action2.Items = {'No Action', 'Pick', 'Place', 'Drill', 'Cut', 'Off', 'On'};
            app.Action2.Editable = 'on';
            app.Action2.Value = 'No Action';
            app.Action3 = uidropdown(app.EndEffectorTab);
            app.Action3.Items = {'No Action', 'Pick', 'Place', 'Drill', 'Cut', 'Off', 'On'};
            app.Action3.Editable = 'on';
            app.Action3.Value = 'No Action';
            app.BaseTab = uitab(app.TabGroup2);
            app.BaseTab.Title = 'Base';
            app.KinParLabel_4 = uilabel(app.BaseTab);
            app.KinParLabel_4.Text = 'KinPar';
            app.kgLabel_5 = uilabel(app.BaseTab);
            app.kgLabel_5.Text = 'kg';
            app.aLabel_3 = uilabel(app.BaseTab);
            app.aLabel_3.Text = 'a';
            app.ba = uieditfield(app.BaseTab, 'numeric');
            app.Label_96 = uilabel(app.BaseTab);
            app.Label_96.Text = 'α';
            app.balpha = uieditfield(app.BaseTab, 'numeric');
            app.pLabel_3 = uilabel(app.BaseTab);
            app.pLabel_3.Text = 'p';
            app.bp = uieditfield(app.BaseTab, 'numeric');
            app.nLabel_3 = uilabel(app.BaseTab);
            app.nLabel_3.Text = 'n';
            app.bn = uieditfield(app.BaseTab, 'numeric');
            app.inLabel_3 = uilabel(app.BaseTab);
            app.inLabel_3.Text = 'δin';
            app.bdeltain = uieditfield(app.BaseTab, 'numeric');
            app.outLabel_3 = uilabel(app.BaseTab);
            app.outLabel_3.Text = 'δout';
            app.bdeltaout = uieditfield(app.BaseTab, 'numeric');
            app.mLabel_70 = uilabel(app.BaseTab);
            app.mLabel_70.Text = 'm';
            app.mLabel_71 = uilabel(app.BaseTab);
            app.mLabel_71.Text = 'm';
            app.mLabel_72 = uilabel(app.BaseTab);
            app.mLabel_72.Text = 'm';
            app.mLabel_73 = uilabel(app.BaseTab);
            app.mLabel_73.Text = 'm';
            app.bm = uieditfield(app.BaseTab, 'numeric');
            app.bm.Limits = [0 Inf];
            app.radLabel_33 = uilabel(app.BaseTab);
            app.radLabel_33.Text = ' rad';
            app.radLabel_34 = uilabel(app.BaseTab);
            app.radLabel_34.Text = ' rad';
            app.radLabel_35 = uilabel(app.BaseTab);
            app.radLabel_35.Text = ' rad';
            app.OutputConnectorSizeLabel_2 = uilabel(app.BaseTab);
            app.OutputConnectorSizeLabel_2.Text = 'OutputConnectorSize';
            app.boutconnector = uieditfield(app.BaseTab, 'numeric');
            app.boutconnector.Limits = [1 2];
            app.boutconnector.ValueDisplayFormat = '%.0f';
            app.boutconnector.Value = 1;
            app.AddBase = uibutton(app.BaseTab, 'push');
            app.AddBase.Text = 'Add To Catalog';
            app.showmodulelegendButton = uibutton(app.OwnModulesTab, 'push');
            app.showmodulelegendButton.Text = 'show module legend';
            app.AvailableModulesListBoxLabel = uilabel(app.ModuleManagementTab);
            app.AvailableModulesListBoxLabel.Text = 'Available Modules';
            app.AvailableModulesListBox = uilistbox(app.ModuleManagementTab);
            app.AvailableModulesListBox.Items = {};
            app.AvailableModulesListBox.Value = {};
            app.Label_4 = uilabel(app.ModuleManagementTab);
            app.Label_4.Text = {'Here you can manage your library of modules. You can either select a module from the catalog or add '; 'a module by defining the name and the module parameters. Press apply if you have got all the modules.'};
            app.RemoveButton_4 = uibutton(app.ModuleManagementTab, 'push');
            app.RemoveButton_4.Text = 'Remove';
            app.ListBox_2Label = uilabel(app.ModuleManagementTab);
            app.ListBox_2Label.Text = '#';
            app.MyQuantityListBox = uilistbox(app.ModuleManagementTab);
            app.MyQuantityListBox.Items = {};
            app.MyQuantityListBox.Value = {};
            app.ApplyButton_14 = uibutton(app.ModuleManagementTab, 'push');
            app.ApplyButton_14.Text = 'Apply';
            app.LoadMyModules = uibutton(app.ModuleManagementTab, 'push');
            app.LoadMyModules.Text = 'Reset';
            app.Label_52 = uilabel(app.ModuleManagementTab);
            app.Label_52.Text = '3';
            app.HelpButton_MM = uibutton(app.ModuleManagementTab, 'push');
            app.HelpButton_MM.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_MMPushed, true);
            app.HelpButton_MM.Text = 'Help';
            app.SelectEndEffectorDropDownLabel = uilabel(app.ModuleManagementTab);
            app.SelectEndEffectorDropDownLabel.Text = 'Select End Effector';
            app.SelectEndEffectorDropDown = uidropdown(app.ModuleManagementTab);
            app.SelectEndEffectorDropDown.Items = {'EE0'};
            app.SelectEndEffectorDropDown.Value = 'EE0';
            app.Label_2MM = uilabel(app.ModuleManagementTab);
            app.Label_2MM.Text = '2';
            app.PositionsTab = uitab(app.TabGroup);
            app.PositionsTab.Title = 'Positions';
            app.Label_5 = uilabel(app.PositionsTab);
            app.Label_5.Text = {'Here you can define the positions that should be reached by the tool-center point (TCP). Additionally, you can '; 'define the positions of the obstacles in the workcell, which must be avoided by the robot. You can either '; 'manually define the positions with its coordinates or  load a V-REP file.'};
            app.TabGroup4 = uitabgroup(app.PositionsTab);
            app.VREPTab = uitab(app.TabGroup4);
            app.VREPTab.Title = 'V-REP';
            app.confirmButton = uibutton(app.VREPTab, 'push');
            app.confirmButton.Text = 'confirm';
            app.XSpinnerLabel = uilabel(app.VREPTab);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.VREPTab);
            app.XSpinner.Step = 30;
            app.YSpinnerLabel = uilabel(app.VREPTab);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.VREPTab);
            app.YSpinner.Step = 30;
            app.ZSpinnerLabel = uilabel(app.VREPTab);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.VREPTab);
            app.ZSpinner.Step = 30;
            app.RollSpinnerLabel = uilabel(app.VREPTab);
            app.RollSpinnerLabel.Text = 'Roll';
            app.RollSpinner = uispinner(app.VREPTab);
            app.RollSpinner.Step = 5;
            app.RollSpinner.Limits = [0 360];
            app.YawSpinnerLabel = uilabel(app.VREPTab);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.VREPTab);
            app.YawSpinner.Step = 5;
            app.YawSpinner.Limits = [0 360];
            app.PitchSpinnerLabel = uilabel(app.VREPTab);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.PitchSpinner = uispinner(app.VREPTab);
            app.PitchSpinner.Step = 5;
            app.PitchSpinner.Limits = [0 360];
            app.CartesianCoordinatesLabel = uilabel(app.VREPTab);
            app.CartesianCoordinatesLabel.Text = 'Cartesian Coordinates';
            app.EulerAnglesLabel = uilabel(app.VREPTab);
            app.EulerAnglesLabel.Text = 'Euler Angles';
            app.RotaroundXLabel = uilabel(app.VREPTab);
            app.RotaroundXLabel.Text = 'Rot. around X';
            app.RotaroundYLabel = uilabel(app.VREPTab);
            app.RotaroundYLabel.Text = 'Rot. around Y';
            app.RotaroundZLabel = uilabel(app.VREPTab);
            app.RotaroundZLabel.Text = 'Rot. around Z';
            app.ChooseObstaclesTextAreaLabel = uilabel(app.VREPTab);
            app.ChooseObstaclesTextAreaLabel.Text = 'Choose Obstacles';
            app.ChooseObstaclesTextArea = uitextarea(app.VREPTab);
            app.ChooseObstaclesTextArea.Editable = 'off';
            app.ExploreVrepObstacles = uibutton(app.VREPTab, 'push');
            app.ExploreVrepObstacles.ButtonPushedFcn = createCallbackFcn(app, @ExploreVrepObstaclesButtonPushed, true);
            app.ExploreVrepObstacles.Text = 'Explore';
            app.mmLabel = uilabel(app.VREPTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.VREPTab);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.VREPTab);
            app.mmLabel_3.Text = 'mm';
            app.gradLabel_3 = uilabel(app.VREPTab);
            app.gradLabel_3.Text = 'grad';
            app.gradLabel = uilabel(app.VREPTab);
            app.gradLabel.Text = 'grad';
            app.gradLabel_2 = uilabel(app.VREPTab);
            app.gradLabel_2.Text = 'grad';
            app.StartVrepButton = uibutton(app.VREPTab, 'push');
            app.StartVrepButton.Text = 'Start Vrep';
            app.Label_83 = uilabel(app.VREPTab);
            app.Label_83.Text = '________________________________________________________________________________________________________';
            app.Label_169 = uilabel(app.VREPTab);
            app.Label_169.Text = '1';
            app.Label_170 = uilabel(app.VREPTab);
            app.Label_170.Text = '2';
            app.ManualTab = uitab(app.TabGroup4);
            app.ManualTab.Title = 'Manual';
            app.CartesianCoordinatesLabel_2 = uilabel(app.ManualTab);
            app.CartesianCoordinatesLabel_2.Text = 'Cartesian Coordinates';
            app.EulerAnglesLabel_2 = uilabel(app.ManualTab);
            app.EulerAnglesLabel_2.Text = 'Euler Angles';
            app.confirmButton_manual = uibutton(app.ManualTab, 'push');
            app.confirmButton_manual.Text = 'confirm';
            app.ChooseObstaclesTextArea_2Label = uilabel(app.ManualTab);
            app.ChooseObstaclesTextArea_2Label.Text = 'Choose Obstacles';
            app.ChooseObstaclesTextArea_manual = uitextarea(app.ManualTab);
            app.ChooseObstaclesTextArea_manual.Editable = 'off';
            app.mmLabel_4 = uilabel(app.ManualTab);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_5 = uilabel(app.ManualTab);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.ManualTab);
            app.mmLabel_6.Text = 'mm';
            app.gradLabel_4 = uilabel(app.ManualTab);
            app.gradLabel_4.Text = 'grad';
            app.gradLabel_5 = uilabel(app.ManualTab);
            app.gradLabel_5.Text = 'grad';
            app.gradLabel_6 = uilabel(app.ManualTab);
            app.gradLabel_6.Text = 'grad';
            app.ExploreVrepObstacles_2 = uibutton(app.ManualTab, 'push');
            app.ExploreVrepObstacles_2.Text = 'Explore';
            app.Label_172 = uilabel(app.ManualTab);
            app.Label_172.Text = '1';
            app.Label_173 = uilabel(app.ManualTab);
            app.Label_173.Text = '2';
            app.Label_174 = uilabel(app.ManualTab);
            app.Label_174.Text = '________________________________________________________________________________________________________';
            app.XEditFieldLabel = uilabel(app.ManualTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ManualTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.ManualTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ManualTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.ManualTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ManualTab, 'numeric');
            app.RollEditFieldLabel = uilabel(app.ManualTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.ManualTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.ManualTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.ManualTab, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.ManualTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.ManualTab, 'numeric');
            app.ApplyButton_13 = uibutton(app.PositionsTab, 'push');
            app.ApplyButton_13.Text = 'Apply';
            app.ConfirmedPositionsListBox = uilistbox(app.PositionsTab);
            app.ConfirmedPositionsListBox.Items = {};
            app.ConfirmedPositionsListBox.Value = {};
            app.HelpButton_Pos = uibutton(app.PositionsTab, 'push');
            app.HelpButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_PosPushed, true);
            app.HelpButton_Pos.Text = 'Help';
            app.ConfirmedPositionsLabel = uilabel(app.PositionsTab);
            app.ConfirmedPositionsLabel.Text = 'Confirmed Positions';
            app.PositionListBox = uilistbox(app.PositionsTab);
            app.PositionListBox.Items = {'Base', 'Start', 'End'};
            app.PositionListBox.Enable = 'off';
            app.PositionListBox.Value = 'Base';
            app.PositionTypeLabel = uilabel(app.PositionsTab);
            app.PositionTypeLabel.Text = 'Position Type';
            app.RemoveButton_7 = uibutton(app.PositionsTab, 'push');
            app.RemoveButton_7.Text = 'Remove';
            app.ResetPositionTypeButton = uibutton(app.PositionsTab, 'push');
            app.ResetPositionTypeButton.Text = 'Reset Position Type';
            app.Label_81 = uilabel(app.PositionsTab);
            app.Label_81.Text = '________________________________________________________________________________________________________';
            app.Label_82 = uilabel(app.PositionsTab);
            app.Label_82.Text = '___________________________________________________________________________________________________';
            app.NameEditFieldLabel = uilabel(app.PositionsTab);
            app.NameEditFieldLabel.Enable = 'off';
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PositionsTab, 'text');
            app.NameEditField.Editable = 'off';
            app.NameEditField.Enable = 'off';
            app.AddButton = uibutton(app.PositionsTab, 'push');
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.RemoveButton_10 = uibutton(app.PositionsTab, 'push');
            app.RemoveButton_10.Enable = 'off';
            app.RemoveButton_10.Text = 'Remove';
            app.Label_171 = uilabel(app.PositionsTab);
            app.Label_171.Text = '3';
            app.ConfigurationTab_3 = uitab(app.TabGroup);
            app.ConfigurationTab_3.Title = 'Configuration';
            app.CalculateConfigurationButton = uibutton(app.ConfigurationTab_3, 'push');
            app.CalculateConfigurationButton.Text = 'Calculate Configuration';
            app.BaseEditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.BaseEditFieldLabel.Text = 'Base';
            app.BaseEditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.BaseEditField.Editable = 'off';
            app.Joint1EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint1EditField.Editable = 'off';
            app.Link1EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link1EditFieldLabel.Text = 'Link 1';
            app.Link1EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link1EditField.Editable = 'off';
            app.Joint2EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint2EditField.Editable = 'off';
            app.Joint4EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint4EditField.Editable = 'off';
            app.Link3EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link3EditFieldLabel.Text = 'Link 3';
            app.Link3EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link3EditField.Editable = 'off';
            app.Joint3EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint3EditField.Editable = 'off';
            app.Link2EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link2EditFieldLabel.Text = 'Link 2';
            app.Link2EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link2EditField.Editable = 'off';
            app.Link4EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link4EditFieldLabel.Text = 'Link 4';
            app.Link4EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link4EditField.Editable = 'off';
            app.Joint5EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint5EditField.Editable = 'off';
            app.Link5EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link5EditFieldLabel.Text = 'Link 5';
            app.Link5EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link5EditField.Editable = 'off';
            app.Joint6EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint6EditField.Editable = 'off';
            app.Link6EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link6EditFieldLabel.Text = 'Link 6';
            app.Link6EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link6EditField.Editable = 'off';
            app.Link7EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link7EditFieldLabel.Text = 'Link 7';
            app.Link7EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link7EditField.Editable = 'off';
            app.Joint7EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint7EditFieldLabel.Text = 'Joint 7';
            app.Joint7EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint7EditField.Editable = 'off';
            app.RobotCompositionLabel = uilabel(app.ConfigurationTab_3);
            app.RobotCompositionLabel.Text = 'Robot Composition';
            app.FinishedAssemblyButton = uibutton(app.ConfigurationTab_3, 'push');
            app.FinishedAssemblyButton.Text = 'Finished Assembly';
            app.AssemblyInstructionButton = uibutton(app.ConfigurationTab_3, 'push');
            app.AssemblyInstructionButton.Text = 'Assembly Instruction';
            app.Label_12 = uilabel(app.ConfigurationTab_3);
            app.Label_12.Text = {'Here you can choose you preferred criteria for the robot configurations. Then, you will get suggestions for a robot '; 'configuration. You can read the Assembly Instruction for support. Press Finished Assembly to check, whether the '; 'robot is correctly assembled or not.'};
            app.MonetarySpinnerLabel = uilabel(app.ConfigurationTab_3);
            app.MonetarySpinnerLabel.Text = 'Monetary';
            app.MonetarySpinner = uispinner(app.ConfigurationTab_3);
            app.MonetarySpinner.Limits = [1 5];
            app.MonetarySpinner.ValueChangedFcn = createCallbackFcn(app, @MonetarySpinnerValueChanged, true);
            app.MonetarySpinner.Editable = 'off';
            app.MonetarySpinner.Enable = 'off';
            app.MonetarySpinner.Value = 3;
            app.AssemblySpinnerLabel = uilabel(app.ConfigurationTab_3);
            app.AssemblySpinnerLabel.Text = 'Assembly';
            app.AssemblySpinner = uispinner(app.ConfigurationTab_3);
            app.AssemblySpinner.Limits = [1 5];
            app.AssemblySpinner.ValueChangedFcn = createCallbackFcn(app, @AssemblySpinnerValueChanged, true);
            app.AssemblySpinner.Editable = 'off';
            app.AssemblySpinner.Enable = 'off';
            app.AssemblySpinner.Value = 3;
            app.Joint8EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint8EditFieldLabel.Text = 'Joint 8';
            app.Joint8EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint8EditField.Editable = 'off';
            app.Link8EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link8EditFieldLabel.Text = 'Link 8';
            app.Link8EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link8EditField.Editable = 'off';
            app.Joint9EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Joint9EditFieldLabel.Text = 'Joint 9';
            app.Joint9EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Joint9EditField.Editable = 'off';
            app.Link9EditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.Link9EditFieldLabel.Text = 'Link 9';
            app.Link9EditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.Link9EditField.Editable = 'off';
            app.EndEffectorEditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.EndEffectorEditFieldLabel.Text = 'End Effector';
            app.EndEffectorEditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.EndEffectorEditField.Editable = 'off';
            app.ListBoxConfigs = uilistbox(app.ConfigurationTab_3);
            app.ListBoxConfigs.Items = {'    Choose Configuration'};
            app.ListBoxConfigs.Value = '    Choose Configuration';
            app.PayloadLabel = uilabel(app.ConfigurationTab_3);
            app.PayloadLabel.Text = 'Payload';
            app.MaxPayloadField = uieditfield(app.ConfigurationTab_3, 'numeric');
            app.MaxPayloadField.Limits = [0 100];
            app.MaxPayloadField.Value = 5;
            app.KgLabel_4 = uilabel(app.ConfigurationTab_3);
            app.KgLabel_4.Text = 'Kg';
            app.AssEditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.AssEditField.Value = 'few changes possible';
            app.MonEditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.MonEditField.Value = 'economic';
            app.Label_39 = uilabel(app.ConfigurationTab_3);
            app.Label_39.Text = '';
            app.EnergyConsumptionEditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.EnergyConsumptionEditFieldLabel.Text = 'Energy Consumption';
            app.EnergyConsumptionEditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.EnergyConsumptionEditField.Enable = 'off';
            app.TaskTimeEditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.TaskTimeEditFieldLabel.Text = 'Task Time';
            app.TaskTimeEditField = uieditfield(app.ConfigurationTab_3, 'text');
            app.TaskTimeEditField.Enable = 'off';
            app.AssemblyEditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.AssemblyEditFieldLabel.Text = 'Assembly';
            app.AssemblyEditField = uieditfield(app.ConfigurationTab_3, 'numeric');
            app.AssemblyEditField.Enable = 'off';
            app.ModuleshavetobechangedLabel = uilabel(app.ConfigurationTab_3);
            app.ModuleshavetobechangedLabel.Text = 'Modules have to be changed';
            app.MonetaryEditFieldLabel = uilabel(app.ConfigurationTab_3);
            app.MonetaryEditFieldLabel.Text = 'Monetary';
            app.MonetaryEditField = uieditfield(app.ConfigurationTab_3, 'numeric');
            app.MonetaryEditField.Enable = 'off';
            app.BuyLabel = uilabel(app.ConfigurationTab_3);
            app.BuyLabel.Text = {'Buy'; ''};
            app.NewModulesLabel = uilabel(app.ConfigurationTab_3);
            app.NewModulesLabel.Text = 'New Modules';
            app.Label_43 = uilabel(app.ConfigurationTab_3);
            app.Label_43.Text = '<|';
            app.Label_60 = uilabel(app.ConfigurationTab_3);
            app.Label_60.Text = '2';
            app.Label_61 = uilabel(app.ConfigurationTab_3);
            app.Label_61.Text = '1';
            app.Label_62 = uilabel(app.ConfigurationTab_3);
            app.Label_62.Text = '3';
            app.HelpButton_Conifg = uibutton(app.ConfigurationTab_3, 'push');
            app.HelpButton_Conifg.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_ConifgPushed, true);
            app.HelpButton_Conifg.Text = 'Help';
            app.StopCalculatingButton = uibutton(app.ConfigurationTab_3, 'state');
            app.StopCalculatingButton.Enable = 'off';
            app.StopCalculatingButton.Text = 'Stop Calculating';
            app.TaskTimeSliderLabel = uilabel(app.ConfigurationTab_3);
            app.TaskTimeSliderLabel.Text = 'Task Time';
            app.TaskTimeSlider = uislider(app.ConfigurationTab_3);
            app.TaskTimeSlider.Limits = [1 5];
            app.TaskTimeSlider.MajorTicks = [1 2 3 4 5];
            app.TaskTimeSlider.MajorTickLabels = {'', '', '', '', ''};
            app.TaskTimeSlider.MinorTicks = [];
            app.TaskTimeSlider.Value = 3;
            app.EnergyConsumptionLabel = uilabel(app.ConfigurationTab_3);
            app.EnergyConsumptionLabel.Text = 'Energy Consumption';
            app.Label_141 = uilabel(app.ConfigurationTab_3);
            app.Label_141.Text = '-------';
            app.Label_142 = uilabel(app.ConfigurationTab_3);
            app.Label_142.Text = '-------';
            app.Label_143 = uilabel(app.ConfigurationTab_3);
            app.Label_143.Text = '-------';
            app.Label_144 = uilabel(app.ConfigurationTab_3);
            app.Label_144.Text = '-------';
            app.Label_145 = uilabel(app.ConfigurationTab_3);
            app.Label_145.Text = '-------';
            app.Label_146 = uilabel(app.ConfigurationTab_3);
            app.Label_146.Text = '-------';
            app.Label_147 = uilabel(app.ConfigurationTab_3);
            app.Label_147.Text = '-------';
            app.Label_148 = uilabel(app.ConfigurationTab_3);
            app.Label_148.Text = '-------';
            app.Label_149 = uilabel(app.ConfigurationTab_3);
            app.Label_149.Text = '-------';
            app.Label_150 = uilabel(app.ConfigurationTab_3);
            app.Label_150.Text = '-------';
            app.Label_151 = uilabel(app.ConfigurationTab_3);
            app.Label_151.Text = '-------';
            app.Label_152 = uilabel(app.ConfigurationTab_3);
            app.Label_152.Text = '-------';
            app.Label_153 = uilabel(app.ConfigurationTab_3);
            app.Label_153.Text = '-------';
            app.Label_154 = uilabel(app.ConfigurationTab_3);
            app.Label_154.Text = '-------';
            app.Label_155 = uilabel(app.ConfigurationTab_3);
            app.Label_155.Text = '-------';
            app.Label_156 = uilabel(app.ConfigurationTab_3);
            app.Label_156.Text = {'|'; '|'; '|'; '|'; '|'};
            app.Label_157 = uilabel(app.ConfigurationTab_3);
            app.Label_157.Text = {'|'; '|'; '|'; '|'; '|'};
            app.Label_158 = uilabel(app.ConfigurationTab_3);
            app.Label_158.Text = '-<';
            app.Label_159 = uilabel(app.ConfigurationTab_3);
            app.Label_159.Text = '--';
            app.Label_160 = uilabel(app.ConfigurationTab_3);
            app.Label_160.Text = '--';
            app.Label_161 = uilabel(app.ConfigurationTab_3);
            app.Label_161.Text = '--';
            app.Label_162 = uilabel(app.ConfigurationTab_3);
            app.Label_162.Text = '--';
            app.Label_163 = uilabel(app.ConfigurationTab_3);
            app.Label_163.Text = {'|'; '|'; '|'; '|'; '|'};
            app.Label_164 = uilabel(app.ConfigurationTab_3);
            app.Label_164.Text = {'|'; '|'; '|'; '|'; '|'};
            app.Label_165 = uilabel(app.ConfigurationTab_3);
            app.Label_165.Text = '--';
            app.Label_166 = uilabel(app.ConfigurationTab_3);
            app.Label_166.Text = '--';
            app.Label_167 = uilabel(app.ConfigurationTab_3);
            app.Label_167.Text = '--';
            app.Label_168 = uilabel(app.ConfigurationTab_3);
            app.Label_168.Text = '--';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.AutomaticMoveButton = uibutton(app.CalibrationTab, 'push');
            app.AutomaticMoveButton.Enable = 'off';
            app.AutomaticMoveButton.Text = 'Automatic Move';
            app.STOPButton = uibutton(app.CalibrationTab, 'push');
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.ConfirmCalibratedPositionButton = uibutton(app.CalibrationTab, 'push');
            app.ConfirmCalibratedPositionButton.Enable = 'off';
            app.ConfirmCalibratedPositionButton.Text = 'Confirm Calibrated Position';
            app.TabGroup3 = uitabgroup(app.CalibrationTab);
            app.TaskSpaceTab = uitab(app.TabGroup3);
            app.TaskSpaceTab.Title = 'Task Space';
            app.EndeffectorSpeedEditFieldLabel = uilabel(app.TaskSpaceTab);
            app.EndeffectorSpeedEditFieldLabel.Text = 'Endeffector Speed';
            app.EndeffectorSpeedEditField = uieditfield(app.TaskSpaceTab, 'numeric');
            app.EndeffectorSpeedEditField.Limits = [0 0.25];
            app.EndeffectorSpeedEditField.Value = 0.25;
            app.SetButtonTaskSpace = uibutton(app.TaskSpaceTab, 'push');
            app.SetButtonTaskSpace.Text = 'Set & Control';
            app.msLabel_2 = uilabel(app.TaskSpaceTab);
            app.msLabel_2.Text = 'm/s';
            app.Label_99 = uilabel(app.TaskSpaceTab);
            app.Label_99.Text = '2';
            app.JointSpaceTab = uitab(app.TabGroup3);
            app.JointSpaceTab.Title = 'Joint Space';
            app.SelectJointListBoxLabel = uilabel(app.JointSpaceTab);
            app.SelectJointListBoxLabel.Text = 'Select Joint';
            app.SelectJointListBox = uilistbox(app.JointSpaceTab);
            app.SelectJointListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SelectJointListBox.Value = '1';
            app.JointSpeedEditFieldLabel = uilabel(app.JointSpaceTab);
            app.JointSpeedEditFieldLabel.Text = 'Joint Speed';
            app.JointSpeedEditField = uieditfield(app.JointSpaceTab, 'numeric');
            app.JointSpeedEditField.Limits = [0 0.3142];
            app.JointSpeedEditField.Value = 0.31;
            app.SetButtonJointSpace = uibutton(app.JointSpaceTab, 'push');
            app.SetButtonJointSpace.Text = 'Set & Control';
            app.Label_86 = uilabel(app.JointSpaceTab);
            app.Label_86.Text = '2';
            app.radsLabel = uilabel(app.JointSpaceTab);
            app.radsLabel.Text = 'rad/s';
            app.Label_11 = uilabel(app.CalibrationTab);
            app.Label_11.Text = {'Here you have to check whether the defined positions correspond to the desired position in the real-world. '; 'You can let the robot automatically move to the defined position. Press stop if the robot is close to an obstacle. '; 'Press confirm if the position is as desired. Change positions with cartesian or joint control below. '};
            app.ApplyConfiguration = uibutton(app.CalibrationTab, 'push');
            app.ApplyConfiguration.Text = 'Apply';
            app.ConnectButton = uibutton(app.CalibrationTab, 'push');
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.CalibrationTab, 'push');
            app.DisconnectButton.Text = 'Disconnect';
            app.Label_65 = uilabel(app.CalibrationTab);
            app.Label_65.Text = '1';
            app.Label_66 = uilabel(app.CalibrationTab);
            app.Label_66.Text = '3';
            app.Label_67 = uilabel(app.CalibrationTab);
            app.Label_67.Text = '4';
            app.HelpButton_Cal = uibutton(app.CalibrationTab, 'push');
            app.HelpButton_Cal.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_CalPushed, true);
            app.HelpButton_Cal.Text = 'Help';
            app.CalibratedPositionsThistabLabel = uilabel(app.CalibrationTab);
            app.CalibratedPositionsThistabLabel.Text = 'Calibrated Positions (This tab) ';
            app.ListBox_3Label = uilabel(app.CalibrationTab);
            app.ListBox_3Label.Text = '';
            app.calPosCalTab = uilistbox(app.CalibrationTab);
            app.calPosCalTab.Items = {};
            app.calPosCalTab.Value = {};
            app.RemoveCalibratedPositionButton = uibutton(app.CalibrationTab, 'push');
            app.RemoveCalibratedPositionButton.Text = 'Remove Calibrated Position';
            app.Label_87 = uilabel(app.CalibrationTab);
            app.Label_87.Text = '__________________________________________________________________________________________________________';
            app.Label_88 = uilabel(app.CalibrationTab);
            app.Label_88.Text = '__________________________________________________________________________________________________________';
            app.Label_89 = uilabel(app.CalibrationTab);

            app.Label_89.Text = '__________________________________________________________________________________________________________';
            app.PositionListBoxCalTab = uilistbox(app.CalibrationTab);
            app.PositionListBoxCalTab.Items = {'Start', 'End'};
            app.PositionListBoxCalTab.Value = 'Start';
            app.PositionstocalibrateLabel = uilabel(app.CalibrationTab);
            app.PositionstocalibrateLabel.Text = 'Positions to calibrate';
            app.TeachingTab = uitab(app.TabGroup);
            app.TeachingTab.Title = 'Teaching';
            app.ResetSeq = uibutton(app.TeachingTab, 'push');
            app.ResetSeq.Text = 'Reset';
            app.Label_33 = uilabel(app.TeachingTab);
            app.Label_33.Text = {'Here you could define the tasks of your robot. Set the order of the positions and actions that the robot should do. '; 'Furthermore, you can define how to reach the particular positions, add delays, and send or receive signals.'};
            app.AvailablePostionsLabel = uilabel(app.TeachingTab);
            app.AvailablePostionsLabel.Text = {'Available '; 'Postions'};
            app.AvailablePostionsListBox = uilistbox(app.TeachingTab);
            app.AvailablePostionsListBox.Items = {'Start', 'End'};
            app.AvailablePostionsListBox.Value = 'Start';
            app.SequenceListBoxLabel = uilabel(app.TeachingTab);
            app.SequenceListBoxLabel.Text = 'Sequence';
            app.SequenceListBox = uilistbox(app.TeachingTab);
            app.SequenceListBox.Items = {};
            app.SequenceListBox.Value = {};
            app.AvailableActionsLabel = uilabel(app.TeachingTab);
            app.AvailableActionsLabel.Text = {'Available '; 'Actions'};
            app.AvailableActionsListBox = uilistbox(app.TeachingTab);
            app.AvailableActionsListBox.Items = {'Pick', 'Place'};
            app.AvailableActionsListBox.Value = 'Pick';
            app.TypeofMovementLabel = uilabel(app.TeachingTab);
            app.TypeofMovementLabel.Text = {'Type of '; 'Movement'};
            app.TypeofMovementListBox = uilistbox(app.TeachingTab);
            app.TypeofMovementListBox.Items = {'PTP', 'LINE', 'CIRC'};
            app.TypeofMovementListBox.Enable = 'off';
            app.TypeofMovementListBox.Value = 'PTP';
            app.FollowSequenceButton = uibutton(app.TeachingTab, 'push');
            app.FollowSequenceButton.Enable = 'off';
            app.FollowSequenceButton.Text = 'Follow Sequence';
            app.ConfirmSequenceButton = uibutton(app.TeachingTab, 'push');
            app.ConfirmSequenceButton.Enable = 'off';
            app.ConfirmSequenceButton.Text = 'Confirm Sequence';
            app.WaitTimeEditFieldLabel = uilabel(app.TeachingTab);
            app.WaitTimeEditFieldLabel.Text = {'Wait'; 'Time'};
            app.WaitTimeEditField = uieditfield(app.TeachingTab, 'numeric');
            app.WaitTimeEditField.Limits = [0 Inf];
            app.secondsLabel = uilabel(app.TeachingTab);
            app.secondsLabel.Text = 'seconds';
            app.AddDelayToSeq = uibutton(app.TeachingTab, 'push');
            app.AddDelayToSeq.Text = 'Add Delay';
            app.AddSignalToSeq = uibutton(app.TeachingTab, 'push');
            app.AddSignalToSeq.Enable = 'off';
            app.AddSignalToSeq.Text = 'Add Signal';
            app.AvailableSignalsListBoxLabel = uilabel(app.TeachingTab);
            app.AvailableSignalsListBoxLabel.Text = {'Available'; 'Signals'};
            app.AvailableSignalsListBox = uilistbox(app.TeachingTab);
            app.AvailableSignalsListBox.Items = {'Robert'};
            app.AvailableSignalsListBox.Enable = 'off';
            app.AvailableSignalsListBox.Value = 'Robert';
            app.SequenceNameEditFieldLabel = uilabel(app.TeachingTab);
            app.SequenceNameEditFieldLabel.Text = 'Sequence Name';
            app.SequenceNameEditField = uieditfield(app.TeachingTab, 'text');
            app.DefinedSequencesLabel = uilabel(app.TeachingTab);
            app.DefinedSequencesLabel.Text = {'Defined '; 'Sequences'};
            app.DefinedSequencesListBox = uilistbox(app.TeachingTab);
            app.DefinedSequencesListBox.Items = {};
            app.DefinedSequencesListBox.Value = {};
            app.Label_38 = uilabel(app.TeachingTab);
            app.Label_38.Text = '__________________________________________________________________________________________________________';
            app.AddPositionButton = uibutton(app.TeachingTab, 'push');
            app.AddPositionButton.Text = 'Add Position';
            app.RemoveButton_8 = uibutton(app.TeachingTab, 'push');
            app.RemoveButton_8.Text = 'Remove';
            app.AddActionButton = uibutton(app.TeachingTab, 'push');
            app.AddActionButton.ButtonPushedFcn = createCallbackFcn(app, @AddActionButtonPushed, true);
            app.AddActionButton.Text = 'Add Action';
            app.ExpertModeCheckBox = uicheckbox(app.TeachingTab);
            app.ExpertModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExpertModeCheckBoxValueChanged, true);
            app.ExpertModeCheckBox.Text = 'Expert Mode';
            app.RemoveButton_9 = uibutton(app.TeachingTab, 'push');
            app.RemoveButton_9.Text = 'Remove';
            app.LoadSequencesButton = uibutton(app.TeachingTab, 'push');
            app.LoadSequencesButton.Text = 'Load Sequences';
            app.ApplyButton_15 = uibutton(app.TeachingTab, 'push');
            app.ApplyButton_15.Text = 'Apply';
            app.Label_68 = uilabel(app.TeachingTab);
            app.Label_68.Text = '2';
            app.Label_69 = uilabel(app.TeachingTab);
            app.Label_69.Text = '1';
            app.Label_70 = uilabel(app.TeachingTab);
            app.Label_70.Text = '3';
            app.Label_71 = uilabel(app.TeachingTab);
            app.Label_71.Text = '4';
            app.HelpButton_teach = uibutton(app.TeachingTab, 'push');
            app.HelpButton_teach.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_teachPushed, true);
            app.HelpButton_teach.Text = 'Help';
            app.STOPteaching = uibutton(app.TeachingTab, 'push');
            app.STOPteaching.Enable = 'off';
            app.STOPteaching.Text = 'STOP';
            app.VelocityEditFieldLabel = uilabel(app.TeachingTab);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityTrajectory = uieditfield(app.TeachingTab, 'numeric');
            app.VelocityTrajectory.Limits = [0 0.25];
            app.VelocityTrajectory.Value = 0.25;
            app.msLabel_4 = uilabel(app.TeachingTab);
            app.msLabel_4.Text = 'm/s';
            app.GravityCompensationSwitchLabel = uilabel(app.TeachingTab);
            app.GravityCompensationSwitchLabel.Text = {'Gravity'; 'Compensation'};
            app.GravityCompensationSwitch = uiswitch(app.TeachingTab, 'toggle');
            app.NameEditField_2Label = uilabel(app.TeachingTab);
            app.NameEditField_2Label.Text = 'Name';
            app.GCname = uieditfield(app.TeachingTab, 'text');
            app.GCname.Enable = 'off';
            app.GCname.Value = 'Homing';
            app.DesiredValueEditFieldLabel = uilabel(app.TeachingTab);
            app.DesiredValueEditFieldLabel.Text = {'Desired'; 'Value'};
            app.DesiredValueEditField = uieditfield(app.TeachingTab, 'text');
            app.DesiredValueEditField.Enable = 'off';
            app.DesiredValueEditField.Value = '0';
            app.SignalOutIn = uiswitch(app.TeachingTab, 'toggle');
            app.SignalOutIn.Items = {'Out', 'In'};
            app.SignalOutIn.Enable = 'off';
            app.SignalOutIn.Value = 'Out';
            app.ModulesdefinedLabel = uilabel(app.UIFigure);
            app.ModulesdefinedLabel.Text = 'Modules defined';
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '';
            app.Lamp_M = uilamp(app.UIFigure);
            app.PositionsdefinedLabel = uilabel(app.UIFigure);
            app.PositionsdefinedLabel.Text = 'Positions defined';
            app.Lamp_3Label = uilabel(app.UIFigure);
            app.Lamp_3Label.Text = '';
            app.Lamp_P = uilamp(app.UIFigure);
            app.RobotassembledLabel = uilabel(app.UIFigure);
            app.RobotassembledLabel.Text = 'Robot assembled';
            app.Lamp_4Label = uilabel(app.UIFigure);
            app.Lamp_4Label.Text = '';
            app.Lamp_A = uilamp(app.UIFigure);
            app.RobotcalibratedLabel = uilabel(app.UIFigure);
            app.RobotcalibratedLabel.Text = 'Robot calibrated';
            app.Lamp_5Label = uilabel(app.UIFigure);
            app.Lamp_5Label.Text = '';
            app.Lamp_C = uilamp(app.UIFigure);
            app.RobotteachingLabel = uilabel(app.UIFigure);
            app.RobotteachingLabel.Text = 'Robot teaching';
            app.Lamp_T = uilamp(app.UIFigure);
            app.ReadyLabel_3 = uilabel(app.UIFigure);
            app.ReadyLabel_3.Text = 'Ready';
            app.Lamp_R = uilamp(app.UIFigure);
            app.WorkflowLabel = uilabel(app.UIFigure);
            app.WorkflowLabel.Text = 'Workflow';
            app.StateTextAreaLabel = uilabel(app.UIFigure);
            app.StateTextAreaLabel.Text = 'State';
            app.State = uitextarea(app.UIFigure);
            app.State.Editable = 'off';
            app.State.Value = {'This is only a DEMO to demonstrate how a modular robot could be configurated. In this GUI is no function implemented!'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end